classdef currentinjSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IndividualNeuronsTab            matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        CurrentInjectionAnalysesLabel   matlab.ui.control.Label
        PulsestartmsEditFieldLabel      matlab.ui.control.Label
        PulsestartmsEditField           matlab.ui.control.NumericEditField
        PulseendmsEditFieldLabel        matlab.ui.control.Label
        PulseendmsEditField             matlab.ui.control.NumericEditField
        BaselinedurationmsEditFieldLabel  matlab.ui.control.Label
        BaselinedurationmsEditField     matlab.ui.control.NumericEditField
        NumberofrunsDropDownLabel       matlab.ui.control.Label
        NumberofrunsDropDown            matlab.ui.control.DropDown
        StartinginjectionpAEditFieldLabel  matlab.ui.control.Label
        StartinginjectionpAEditField    matlab.ui.control.NumericEditField
        InjectiondeltapAEditFieldLabel  matlab.ui.control.Label
        InjectiondeltapAEditField       matlab.ui.control.NumericEditField
        NumberofsweepsEditFieldLabel    matlab.ui.control.Label
        NumberofsweepsEditField         matlab.ui.control.NumericEditField
        Run1Label                       matlab.ui.control.Label
        Run2Label                       matlab.ui.control.Label
        StartinginjectionpAEditField_2Label  matlab.ui.control.Label
        StartinginjectionpAEditField_2  matlab.ui.control.NumericEditField
        InjectiondeltapAEditField_2Label  matlab.ui.control.Label
        InjectiondeltapAEditField_2     matlab.ui.control.NumericEditField
        NumberofsweepsEditField_2Label  matlab.ui.control.Label
        NumberofsweepsEditField_2       matlab.ui.control.NumericEditField
        GroupComparisonsTab             matlab.ui.container.Tab
        CurrentInjectionAnalysesLabel_2  matlab.ui.control.Label
        ControlGroupEditFieldLabel      matlab.ui.control.Label
        ControlGroupEditField           matlab.ui.control.EditField
        ExperimentalGroupEditFieldLabel  matlab.ui.control.Label
        ExperimentalGroupEditField      matlab.ui.control.EditField
        CtrlgroupexamplecellEditFieldLabel  matlab.ui.control.Label
        CtrlgroupexamplecellEditField   matlab.ui.control.NumericEditField
        ExpgroupexamplecellEditFieldLabel  matlab.ui.control.Label
        ExpgroupexamplecellEditField    matlab.ui.control.NumericEditField
        PickFolderButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        FolderTBDLabel                  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        ciDir % main directory for cell
        fldrs % folders for each isi
        ciData % paired pulse curve data
        ciCompData % paired pulse grp comparison data
        plotColor %color for plotting
    end
    

    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.ciDir app.fldrs fIO
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neurons')
                app.ciDir=uigetdir;
                cd(app.ciDir);
                contents = dir;
                fPP = {contents.name}';
                app.fldrs = cell(1,app.NumberofrunsDropDown.Value);
                for ii = 1:str2double(app.NumberofrunsDropDown.Value)
                    app.fldrs{ii} = fPP{end-str2double(app.NumberofrunsDropDown.Value)+ii};
                end
                thisPt = find(app.ciDir=='/');
                app.FolderTBDLabel.Text = app.ciDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.ciDir=uigetdir;
                thisPt = find(app.ciDir=='/');
                app.FolderTBDLabel.Text = app.ciDir(thisPt(end)+1:end);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            clear app.ciData
            
            
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neurons')
                
                %get color
                app.plotColor = zeros(1,3);
                if strcmp(app.PlotcolorDropDown.Value,'Black')
                    app.plotColor = [0 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Blue')
                    app.plotColor = [.0625 .2545 .433];
                elseif strcmp(app.PlotcolorDropDown.Value,'Red')
                    app.plotColor = [.3922 0 0];
                elseif strcmp(app.PlotcolorDropDown.Value,'Green')
                    app.plotColor = [.35 .6 .25];
                elseif strcmp(app.PlotcolorDropDown.Value,'Orange')
                    app.plotColor = [.85 .35 .1];
                elseif strcmp(app.PlotcolorDropDown.Value,'Purple')
                    app.plotColor = [.5 .33 .55];
                elseif strcmp(app.PlotcolorDropDown.Value,'Pink')
                    app.plotColor = [.5 .11 .2];
                end
                
                %get data
                close all
                app.ciData = currentinjFxn(app);
                
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualNeuronsTab = uitab(app.TabGroup);
            app.IndividualNeuronsTab.Title = 'Individual Neurons';
            app.InitialParamsLabel = uilabel(app.IndividualNeuronsTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.CurrentInjectionAnalysesLabel = uilabel(app.IndividualNeuronsTab);
            app.CurrentInjectionAnalysesLabel.Text = 'Current Injection Analyses';
            app.PulsestartmsEditFieldLabel = uilabel(app.IndividualNeuronsTab);
            app.PulsestartmsEditFieldLabel.Text = 'Pulse start (ms)';
            app.PulsestartmsEditField = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.PulsestartmsEditField.Limits = [0 Inf];
            app.PulsestartmsEditField.ValueDisplayFormat = '%11.10g';
            app.PulsestartmsEditField.Value = 546.9;
            app.PulseendmsEditFieldLabel = uilabel(app.IndividualNeuronsTab);
            app.PulseendmsEditFieldLabel.Text = 'Pulse end (ms)';
            app.PulseendmsEditField = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.PulseendmsEditField.Limits = [0 Inf];
            app.PulseendmsEditField.ValueDisplayFormat = '%11.10g';
            app.PulseendmsEditField.Value = 1146.8;
            app.BaselinedurationmsEditFieldLabel = uilabel(app.IndividualNeuronsTab);
            app.BaselinedurationmsEditFieldLabel.Text = 'Baseline duration (ms)';
            app.BaselinedurationmsEditField = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.BaselinedurationmsEditField.Value = 100;
            app.NumberofrunsDropDownLabel = uilabel(app.IndividualNeuronsTab);
            app.NumberofrunsDropDownLabel.Text = 'Number of runs';
            app.NumberofrunsDropDown = uidropdown(app.IndividualNeuronsTab);
            app.NumberofrunsDropDown.Items = {'1', '2'};
            app.NumberofrunsDropDown.Value = '2';
            app.StartinginjectionpAEditFieldLabel = uilabel(app.IndividualNeuronsTab);
            app.StartinginjectionpAEditFieldLabel.Text = 'Starting injection (pA)';
            app.StartinginjectionpAEditField = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.StartinginjectionpAEditField.ValueDisplayFormat = '%11.10g';
            app.StartinginjectionpAEditField.Value = -100;
            app.InjectiondeltapAEditFieldLabel = uilabel(app.IndividualNeuronsTab);
            app.InjectiondeltapAEditFieldLabel.Text = 'Injection delta (pA)';
            app.InjectiondeltapAEditField = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.InjectiondeltapAEditField.ValueDisplayFormat = '%11.10g';
            app.InjectiondeltapAEditField.Value = 10;
            app.NumberofsweepsEditFieldLabel = uilabel(app.IndividualNeuronsTab);
            app.NumberofsweepsEditFieldLabel.Text = 'Number of sweeps';
            app.NumberofsweepsEditField = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.NumberofsweepsEditField.Value = 21;
            app.Run1Label = uilabel(app.IndividualNeuronsTab);
            app.Run1Label.Text = 'Run 1';
            app.Run2Label = uilabel(app.IndividualNeuronsTab);
            app.Run2Label.Text = 'Run 2';
            app.StartinginjectionpAEditField_2Label = uilabel(app.IndividualNeuronsTab);
            app.StartinginjectionpAEditField_2Label.Text = 'Starting injection (pA)';
            app.StartinginjectionpAEditField_2 = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.StartinginjectionpAEditField_2.ValueDisplayFormat = '%11.10g';
            app.StartinginjectionpAEditField_2.Value = -200;
            app.InjectiondeltapAEditField_2Label = uilabel(app.IndividualNeuronsTab);
            app.InjectiondeltapAEditField_2Label.Text = 'Injection delta (pA)';
            app.InjectiondeltapAEditField_2 = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.InjectiondeltapAEditField_2.ValueDisplayFormat = '%11.10g';
            app.InjectiondeltapAEditField_2.Value = 25;
            app.NumberofsweepsEditField_2Label = uilabel(app.IndividualNeuronsTab);
            app.NumberofsweepsEditField_2Label.Text = 'Number of sweeps';
            app.NumberofsweepsEditField_2 = uieditfield(app.IndividualNeuronsTab, 'numeric');
            app.NumberofsweepsEditField_2.Value = 25;
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.CurrentInjectionAnalysesLabel_2 = uilabel(app.GroupComparisonsTab);
            app.CurrentInjectionAnalysesLabel_2.Text = 'Current Injection Analyses';
            app.ControlGroupEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ControlGroupEditFieldLabel.Text = 'Control Group';
            app.ControlGroupEditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExperimentalGroupEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExperimentalGroupEditFieldLabel.Text = 'Experimental Group';
            app.ExperimentalGroupEditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.CtrlgroupexamplecellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.CtrlgroupexamplecellEditFieldLabel.Text = 'Ctrl group, example cell ';
            app.CtrlgroupexamplecellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.CtrlgroupexamplecellEditField.Value = 1;
            app.ExpgroupexamplecellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExpgroupexamplecellEditFieldLabel.Text = 'Exp group, example cell ';
            app.ExpgroupexamplecellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExpgroupexamplecellEditField.Value = 1;
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlotcolorDropDownLabel = uilabel(app.UIFigure);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.UIFigure);
            app.PlotcolorDropDown.Items = {'Black', 'Blue', 'Red', 'Green', 'Orange', 'Purple', 'Pink'};
            app.PlotcolorDropDown.Value = 'Black';
            app.SamplerateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = currentinjSuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end