classdef sponCurrentSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PickFolderButton                matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SpontaneousPSCAnalysisLabel     matlab.ui.control.Label
        FolderTBDLabel                  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IndividualNeuronAnalysisTab     matlab.ui.container.Tab
        InitialParamsLabel              matlab.ui.control.Label
        DetectionParamsLabel            matlab.ui.control.Label
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        RainjectionstartmsEditFieldLabel  matlab.ui.control.Label
        RainjectionstartmsEditField     matlab.ui.control.NumericEditField
        CurrentSwitchLabel              matlab.ui.control.Label
        CurrentSwitch                   matlab.ui.control.RockerSwitch
        RainjectionendmsEditFieldLabel  matlab.ui.control.Label
        RainjectionendmsEditField       matlab.ui.control.NumericEditField
        LengthofsweepsecEditFieldLabel  matlab.ui.control.Label
        LengthofsweepsecEditField       matlab.ui.control.NumericEditField
        NoiseSDEditFieldLabel           matlab.ui.control.Label
        NoiseSDEditField                matlab.ui.control.NumericEditField
        TemplateFactorEditFieldLabel    matlab.ui.control.Label
        TemplateFactorEditField         matlab.ui.control.NumericEditField
        NumberofsweepsEditFieldLabel    matlab.ui.control.Label
        NumberofsweepsEditField         matlab.ui.control.NumericEditField
        GroupComparisonsTab             matlab.ui.container.Tab
        ParamsLabel                     matlab.ui.control.Label
        SamplerateHzEditField_2Label    matlab.ui.control.Label
        SamplerateHzEditField_2         matlab.ui.control.NumericEditField
        CurrentSwitch_2Label            matlab.ui.control.Label
        CurrentSwitch_2                 matlab.ui.control.RockerSwitch
        ControlGroupblueEditFieldLabel  matlab.ui.control.Label
        ControlGroupblueEditField       matlab.ui.control.EditField
        ExperimentalGroupEditFieldLabel  matlab.ui.control.Label
        ExperimentalGroupEditField      matlab.ui.control.EditField
        CtrlgroupexamplecellEditFieldLabel  matlab.ui.control.Label
        CtrlgroupexamplecellEditField   matlab.ui.control.NumericEditField
        ExpgroupexamplecellEditFieldLabel  matlab.ui.control.Label
        ExpgroupexamplecellEditField    matlab.ui.control.NumericEditField
        PlotcolorDropDownLabel          matlab.ui.control.Label
        PlotcolorDropDown               matlab.ui.control.DropDown
    end


    properties (Access = public)
        spscDir % folder director
        spscFiles % subfolders, if necessary
        spscData % Description
        spscCompData %GROUP comparison data
        plotColor %colors
    end


    methods (Access = private)

        % Button pushed function: PickFolderButton
        function PickFolderButtonPushed(app, event)
            clear app.spscDir app.fldrs fSPSC

            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                app.spscDir=uigetdir;
                thisPt = find(app.spscDir=='/');
                app.FolderTBDLabel.Text = app.spscDir(thisPt(end)+1:end);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.spscDir=uigetdir;
                thisPt = find(app.spscDir=='/');
                app.FolderTBDLabel.Text = app.spscDir(thisPt(end)+1:end);
            end
                
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
              
            % get color
            app.plotColor = zeros(11,3);
            if app.PlotcolorDropDown.Value == 'Black_'
                app.plotColor(:,1) = 0:.9/10:.9;
                app.plotColor(:,2) = 0:.9/10:.9;
                app.plotColor(:,3) = 0:.9/10:.9;
            elseif app.PlotcolorDropDown.Value == 'Blue__'
                app.plotColor(:,1) = .0625:(.3333-.0625)/10:.3333;
                app.plotColor(:,2) = .2545:(.8039-.2545)/10:.8039;
                app.plotColor(:,3) = .433:(1-.433)/10:1;
            elseif app.PlotcolorDropDown.Value == 'Red___'
                app.plotColor(:,1) = .3922:(.8824-.3922)/10:.8824;
                app.plotColor(:,2) = 0;
                app.plotColor(:,3) = 0;
            elseif app.PlotcolorDropDown.Value == 'Green_'
                app.plotColor(:,1) = .35:(.5833-.35)/10:.5833;
                app.plotColor(:,2) = .6:(1-.6)/10:1;
                app.plotColor(:,3) = .25:(.4167-.25)/10:.4167;
            elseif app.PlotcolorDropDown.Value == 'Orange'
                app.plotColor(:,1) = .85:(1-.85)/10:1;
                app.plotColor(:,2) = .35:(.6-.35)/10:.6;
                app.plotColor(:,3) = .1:(.3-.1)/10:.3;
            elseif app.PlotcolorDropDown.Value == 'Purple'
                app.plotColor(:,1) = .6:(1-.6)/10:1;
                app.plotColor(:,2) = .4:(.67-.4)/10:.67;
                app.plotColor(:,3) = .67:(1-.67)/10:1;
            elseif app.PlotcolorDropDown.Value == 'Pink__'
                app.plotColor(:,1) = .67:(1-.67)/10:1;
                app.plotColor(:,2) = .2:(.655-.2)/10:.655;
                app.plotColor(:,3) = .25:(.72-.25)/10:.72;
            end
            
            % function here
            if strcmp(app.TabGroup.SelectedTab.Title,'Individual Neuron Analysis')
                app.spscData = sponPSCFxn(app);
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Group Comparisons')
                app.spscCompData = seCompFxn(app);
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clear save_file path_save
            [save_file,path_save]=uiputfile;
            full_save_file=fullfile(path_save,save_file);
            save(full_save_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PickFolderButton = uibutton(app.UIFigure, 'push');
            app.PickFolderButton.ButtonPushedFcn = createCallbackFcn(app, @PickFolderButtonPushed, true);
            app.PickFolderButton.Text = 'Pick Folder';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SpontaneousPSCAnalysisLabel = uilabel(app.UIFigure);
            app.SpontaneousPSCAnalysisLabel.Text = 'Spontaneous PSC Analysis';
            app.FolderTBDLabel = uilabel(app.UIFigure);
            app.FolderTBDLabel.Text = 'Folder TBD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualNeuronAnalysisTab = uitab(app.TabGroup);
            app.IndividualNeuronAnalysisTab.Title = 'Individual Neuron Analysis';
            app.InitialParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.InitialParamsLabel.Text = 'Initial Params';
            app.DetectionParamsLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.DetectionParamsLabel.Text = 'Detection Params';
            app.SamplerateHzEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.SamplerateHzEditField.Limits = [0 Inf];
            app.SamplerateHzEditField.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField.Value = 10000;
            app.RainjectionstartmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionstartmsEditFieldLabel.Text = 'Ra injection start (ms)';
            app.RainjectionstartmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionstartmsEditField.Limits = [0 Inf];
            app.RainjectionstartmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionstartmsEditField.Value = 562.5;
            app.CurrentSwitchLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.CurrentSwitchLabel.Text = 'Current';
            app.CurrentSwitch = uiswitch(app.IndividualNeuronAnalysisTab, 'rocker');
            app.CurrentSwitch.Items = {'IPSC', 'EPSC'};
            app.CurrentSwitch.Value = 'IPSC';
            app.RainjectionendmsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.RainjectionendmsEditFieldLabel.Text = 'Ra injection end (ms)';
            app.RainjectionendmsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.RainjectionendmsEditField.Limits = [0 Inf];
            app.RainjectionendmsEditField.ValueDisplayFormat = '%11.10g';
            app.RainjectionendmsEditField.Value = 662.4;
            app.LengthofsweepsecEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.LengthofsweepsecEditFieldLabel.Text = 'Length of sweep (sec)';
            app.LengthofsweepsecEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.LengthofsweepsecEditField.Limits = [0 Inf];
            app.LengthofsweepsecEditField.ValueDisplayFormat = '%11.10g';
            app.LengthofsweepsecEditField.Value = 20;
            app.NoiseSDEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.NoiseSDEditFieldLabel.Text = 'Noise SD';
            app.NoiseSDEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.NoiseSDEditField.Limits = [0 Inf];
            app.NoiseSDEditField.ValueDisplayFormat = '%11.10g';
            app.NoiseSDEditField.Value = 5;
            app.TemplateFactorEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.TemplateFactorEditFieldLabel.Text = 'Template Factor';
            app.TemplateFactorEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.TemplateFactorEditField.Limits = [0 Inf];
            app.TemplateFactorEditField.ValueDisplayFormat = '%11.10g';
            app.TemplateFactorEditField.Value = 10;
            app.NumberofsweepsEditFieldLabel = uilabel(app.IndividualNeuronAnalysisTab);
            app.NumberofsweepsEditFieldLabel.Text = 'Number of sweeps';
            app.NumberofsweepsEditField = uieditfield(app.IndividualNeuronAnalysisTab, 'numeric');
            app.NumberofsweepsEditField.Limits = [0 Inf];
            app.NumberofsweepsEditField.ValueDisplayFormat = '%11.10g';
            app.NumberofsweepsEditField.Value = 15;
            app.GroupComparisonsTab = uitab(app.TabGroup);
            app.GroupComparisonsTab.Title = 'Group Comparisons';
            app.ParamsLabel = uilabel(app.GroupComparisonsTab);
            app.ParamsLabel.Text = 'Params';
            app.SamplerateHzEditField_2Label = uilabel(app.GroupComparisonsTab);
            app.SamplerateHzEditField_2Label.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField_2 = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.SamplerateHzEditField_2.Limits = [0 Inf];
            app.SamplerateHzEditField_2.ValueDisplayFormat = '%11.10g';
            app.SamplerateHzEditField_2.Value = 10000;
            app.CurrentSwitch_2Label = uilabel(app.GroupComparisonsTab);
            app.CurrentSwitch_2Label.Text = 'Current';
            app.CurrentSwitch_2 = uiswitch(app.GroupComparisonsTab, 'rocker');
            app.CurrentSwitch_2.Items = {'IPSC', 'EPSC'};
            app.CurrentSwitch_2.Value = 'IPSC';
            app.ControlGroupblueEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ControlGroupblueEditFieldLabel.Text = 'Control Group (blue)';
            app.ControlGroupblueEditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.ExperimentalGroupEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExperimentalGroupEditFieldLabel.Text = 'Experimental Group';
            app.ExperimentalGroupEditField = uieditfield(app.GroupComparisonsTab, 'text');
            app.CtrlgroupexamplecellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.CtrlgroupexamplecellEditFieldLabel.Text = 'Ctrl group, example cell ';
            app.CtrlgroupexamplecellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.CtrlgroupexamplecellEditField.Value = 1;
            app.ExpgroupexamplecellEditFieldLabel = uilabel(app.GroupComparisonsTab);
            app.ExpgroupexamplecellEditFieldLabel.Text = 'Exp group, example cell ';
            app.ExpgroupexamplecellEditField = uieditfield(app.GroupComparisonsTab, 'numeric');
            app.ExpgroupexamplecellEditField.Value = 1;
            app.PlotcolorDropDownLabel = uilabel(app.UIFigure);
            app.PlotcolorDropDownLabel.Text = 'Plot color';
            app.PlotcolorDropDown = uidropdown(app.UIFigure);
            app.PlotcolorDropDown.Items = {'Black_', 'Blue__', 'Red___', 'Green_', 'Orange', 'Purple', 'Pink__'};
            app.PlotcolorDropDown.Value = 'Black_';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sponCurrentSuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end